classdef COMMSYS_GIT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        retrieveuncodedButton           matlab.ui.control.Button
        modulateuncodedButton           matlab.ui.control.Button
        demodBitswithoutcodingTextArea  matlab.ui.control.TextArea
        demodBitswithoutcodingTextAreaLabel  matlab.ui.control.Label
        modulatedbitswithoutcodingTextArea  matlab.ui.control.TextArea
        modulatedbitswithoutcodingTextAreaLabel  matlab.ui.control.Label
        retrieveButton                  matlab.ui.control.Button
        plotBERButton                   matlab.ui.control.Button
        decodedbitsTextArea             matlab.ui.control.TextArea
        decodedbitsTextAreaLabel        matlab.ui.control.Label
        decodeButton                    matlab.ui.control.Button
        demodbitsTextArea               matlab.ui.control.TextArea
        demodbitsTextAreaLabel          matlab.ui.control.Label
        SNRdBEditField                  matlab.ui.control.NumericEditField
        SNRdBEditFieldLabel             matlab.ui.control.Label
        modulatedbitswithcodingTextArea  matlab.ui.control.TextArea
        modulatedbitswithcodingTextAreaLabel  matlab.ui.control.Label
        modulateButton                  matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        channelencodedbitsTextArea      matlab.ui.control.TextArea
        channelencodedbitsLabel         matlab.ui.control.Label
        encodeButton                    matlab.ui.control.Button
        ChannelCodeDropDown             matlab.ui.control.DropDown
        ChannelCodeDropDownLabel        matlab.ui.control.Label
        bitsgeneratedTextArea           matlab.ui.control.TextArea
        bitsgeneratedTextAreaLabel      matlab.ui.control.Label
        generateButton                  matlab.ui.control.Button
        UserInputDropDown               matlab.ui.control.DropDown
        UserInputDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        generatedBits; % Description
        channelBits;
        demodulatedbits;
        decodedbits;
        imgHeight;
        imgWidth;
        imgChannels;
        paddingLength;
        uncodeddemodulatedbits
        BER_values;
        EbN0_values;
        BER_simulated_values;
        EbN0_simulated_values;
    end
   
    methods (Access = private)
        
     function generateRandomBits(app)
            clc;
            close all;
            N =5000;
            
            randomBits = randi([0 1],N,1);
            disp(randomBits);
            randomBitsString = num2str(randomBits');
            randomBitsString = strrep(randomBitsString, ' ', '');
            app.generatedBits = randomBitsString;
            app.bitsgeneratedTextArea.Value = randomBitsString;


        end
       
        
function convertTextToBits(app)
    clc;
    close all;
    % Open a dialog box to select a .txt file
    [file, path] = uigetfile('*.txt', 'Select Text File');
    if isequal(file, 0)
        disp('User canceled file selection.');
        return;
    end

    % Read the text file as ASCII characters to ensure consistency
    textData = fileread(fullfile(path, file));

    % Convert each character to an 8-bit binary string
    binaryText = dec2bin(textData, 8)'; 
    binaryText = binaryText(:);  % Flatten into a single column of '0's and '1's

    % Convert binaryText to numeric array (0s and 1s)
    numericBits = binaryText - '0';

   
    k = 64;
    padSize = mod(-length(numericBits), k);
    if padSize > 0
        numericBits = [numericBits; zeros(padSize, 1)];
    end

 
    app.generatedBits = numericBits;

    
    app.bitsgeneratedTextArea.Value = join(string(binaryText'), '');
end


      function convertImageToBits(app)
    clc;
    close all;
    [file, path] = uigetfile('*.jpg;*.png;*.gif', 'Select Image File');
    if isequal(file, 0)
        return; % Exit if no file is selected
    end 

    imageData = imread(fullfile(path, file));
    
    % Store the original image dimensions in the app
    [app.imgHeight, app.imgWidth, app.imgChannels] = size(imageData);
    
    % Convert the image to a binary stream
    binaryImage = dec2bin(imageData(:), 8)';  % Each pixel is 8 bits, transpose to organize by bits
    binaryStream = binaryImage(:)';           % Reshape to a single row of bits
    app.generatedBits = binaryStream;
    binaryString = binaryStream; 
    app.bitsgeneratedTextArea.Value = binaryString;  

           
        end
        
        function convertAudioToBits(app)
            clc;
            close all;
            [file, path] = uigetfile('*.wav;*.mp3;*.ogg', 'Select Audio File');
    
        [audioData, fs] = audioread(fullfile(path, file));
        audioData = (audioData + 1) / 2; 
        audioDataInt = uint8(audioData * 255);  
        binaryAudio = reshape(dec2bin(audioDataInt, 8).', 1, []);  % Convert to a single row string
        app.generatedBits= binaryAudio;
        app.bitsgeneratedTextArea.Value = binaryAudio;  
              
        end

      function hammingCode(app)

        % Define the bitstream to be encoded
        bitstream = app.generatedBits(:);  % Ensure bitstream is a column vector
    
        % Generate Hamming (7,4) code matrices
        H = hammgen(3);
        G = gen2par(H);
    
        % Pad bitstream if not multiple of 4
        if mod(length(bitstream), 4) ~= 0
            bitstream = [bitstream; zeros(4 - mod(length(bitstream), 4), 1)];
        end
    
        % Reshape bitstream into 4-bit blocks
        bitstream4 = reshape(bitstream, 4, []).';
    
        % Encode each 4-bit block
        encodedStream = zeros(size(bitstream4, 1), 7);
        for i = 1:size(bitstream4, 1)
            encodedStream(i, :) = mod(bitstream4(i, :) * G, 2);
        end
    
        % Flatten encoded stream into a single row
        encodedBitstream = reshape(encodedStream.', [], 1);
        app.channelBits = encodedBitstream;
        % Convert encoded bitstream to a string for display
        encodedBitstreamStr = join(string(encodedBitstream'), ''); % Convert to string and join
        app.channelencodedbitsTextArea.Value = encodedBitstreamStr; % Display encoded bitstream
      end

function BCH127(app)
    % Define BCH (127,64) parameters
    n = 127;  % Codeword length
    k = 64;   % Message length

    % Create a BCH encoder object
    bchEncoder = comm.BCHEncoder(n, k);

    % Convert app.generatedBits to numeric if necessary
    if ischar(app.generatedBits) || isstring(app.generatedBits)
        bitstream = double(app.generatedBits) - '0';  % Convert '0'/'1' to numeric
    else
        bitstream = app.generatedBits;
    end

    % Pad bitstream if not a multiple of k
    if mod(length(bitstream), k) ~= 0
        paddingLength = k - mod(length(bitstream), k);
        bitstream = [bitstream, zeros(1, paddingLength)];
    end

    % Reshape the bitstream into k-bit blocks
    bitstream64 = reshape(bitstream, k, []).';

    % Initialize the encoded bitstream
    encodedBitstream = [];

    % Encode each 64-bit block using the BCH encoder
    for i = 1:size(bitstream64, 1)
        encodedBlock = bchEncoder(bitstream64(i, :).').';  % Encode each block
        encodedBitstream = [encodedBitstream, encodedBlock];  % Concatenate encoded blocks
    end

    % Flatten the encoded bitstream to a single row vector
    encodedBitstream = encodedBitstream(:).';

    % Store the encoded bitstream in the app
    app.channelBits = encodedBitstream;

    % Convert encoded bitstream to string and display
    encodedString = join(string(encodedBitstream), "");
    app.channelencodedbitsTextArea.Value = encodedString;
end


 function BCH63(app)    
    n = 63;  % Codeword length
    k = 51;  % Message length

    % Create a BCH encoder object
    bchEncoder = comm.BCHEncoder(n, k);

    
    if ischar(app.generatedBits) || isstring(app.generatedBits)
        bitstream = double(app.generatedBits) - '0';  % Convert '0'/'1' to numeric
    else
        bitstream = double(app.generatedBits(:).');  % Ensure row vector
    end

    % Calculate number of complete blocks and required padding
    numBlocks = ceil(length(bitstream) / k);
    totalBits = numBlocks * k;
    
    % Pad bitstream if necessary
    if length(bitstream) < totalBits
        bitstream = [bitstream, zeros(1, totalBits - length(bitstream))];
    end

    % Preallocate the encoded bitstream (each k bits become n bits)
    encodedBitstream = zeros(1, numBlocks * n);
    
    % Process each block
    for i = 1:numBlocks
        % Extract current block
        blockStart = (i-1)*k + 1;
        blockEnd = i*k;
        currentBlock = bitstream(blockStart:blockEnd);
        
        % Encode the current block
        encodedBlock = bchEncoder(currentBlock.');
        
        % Store the encoded block
        encodedStart = (i-1)*n + 1;
        encodedEnd = i*n;
        encodedBitstream(encodedStart:encodedEnd) = encodedBlock.';
    end

    % Store the encoded bitstream in the app
    app.channelBits = encodedBitstream;

    % Convert encoded bitstream to string and display
    encodedString = join(string(encodedBitstream), "");
    app.channelencodedbitsTextArea.Value = encodedString;
end


function QPSK(app) 
    % Retrieve encoded bitstream and ensure it’s a row vector
    encodedBitstream = app.channelBits(:).';

    % Pad if bitstream length is odd (QPSK requires 2 bits per symbol)
    if mod(length(encodedBitstream), 2) ~= 0
        encodedBitstream = [encodedBitstream, 0];
    end

    % Convert bit pairs to decimal symbols
    bitPairs = reshape(encodedBitstream, 2, []).';
    decimalSymbols = bi2de(bitPairs, 'left-msb');

    % QPSK modulation
    M = 4;
    qpskSymbols = pskmod(decimalSymbols, M, 0, 'gray');

    % Generate time-domain QPSK signal with pulse shaping
    symbolRate = 1e3;
    samplesPerSymbol = 10;
    fs = symbolRate * samplesPerSymbol;
    timeSignal = upsample(qpskSymbols, samplesPerSymbol);
    pulseShape = ones(1, samplesPerSymbol);
    qpskTimeSignal = filter(pulseShape, 1, timeSignal);

    % Add AWGN noise to QPSK signal
    snrValue = app.SNRdBEditField.Value;
    noisyQpskTimeSignal = awgn(qpskTimeSignal, snrValue, 'measured');

    % Time vector for plotting
    t = (0:length(qpskTimeSignal)-1) / fs;

    % Plot Power Spectral Density
    figure;
    subplot(2, 1, 1);
    pwelch(qpskTimeSignal, [], [], [], fs, 'centered');
    title('Power Spectral Density of Original QPSK Signal');
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');

    subplot(2, 1, 2);
    pwelch(noisyQpskTimeSignal, [], [], [], fs, 'centered');
    title(['Power Spectral Density with AWGN (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');

    % Plot Time-Domain Signals
    figure;
    subplot(2, 1, 1);
    plot(t, real(qpskTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(qpskTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title('Original QPSK Time-Domain Signal');
    xlabel('Time (s)');
    ylabel('Amplitude');
    legend;
    grid on;

    subplot(2, 1, 2);
    plot(t, real(noisyQpskTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(noisyQpskTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title(['Time-Domain Signal with AWGN (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)');
    ylabel('Amplitude');
    legend;
    grid on;

    % Plot Constellation Diagrams
    figure;
    subplot(2, 1, 1);
    scatter(real(qpskSymbols), imag(qpskSymbols), 'filled');
    grid on;
    title('Original QPSK Constellation');

    noisyQpskSymbols = downsample(noisyQpskTimeSignal, samplesPerSymbol);
    subplot(2, 1, 2);
    scatter(real(noisyQpskSymbols), imag(noisyQpskSymbols), 'filled');
    grid on;
    title(['Constellation with AWGN (SNR = ' num2str(snrValue) ' dB)']);

    % Demodulate QPSK
    demodulatedSymbols = pskdemod(noisyQpskSymbols, M, 0, 'gray');
    demodulatedBits = de2bi(demodulatedSymbols, 2, 'left-msb');
    app.demodulatedbits = demodulatedBits;
    demodulatedBitsString = num2str(demodulatedBits(:).', '%d');
    app.demodbitsTextArea.Value = demodulatedBitsString;

    % Display original modulated bitstream
    bitString = num2str(encodedBitstream, '%d');
    app.modulatedbitswithcodingTextArea.Value = bitString;
end


function QAM16(app)
    % Retrieve and prepare the bitstream for 16-QAM
    encodedBitstream = app.channelBits(:).';  % Ensure row vector
    if mod(length(encodedBitstream), 4) ~= 0
        encodedBitstream = [encodedBitstream, zeros(1, 4 - mod(length(encodedBitstream), 4))];
    end

    % Group bits into quads, convert to decimal, and modulate to 16-QAM
    bitQuads = reshape(encodedBitstream, 4, []).';
    decimalSymbols = bi2de(bitQuads, 'left-msb');
    M = 16;  
    qamSymbols = qammod(decimalSymbols, M, 'UnitAveragePower', true);

    % Generate continuous 16-QAM signal with pulse shaping
    symbolRate = 1e3;  
    samplesPerSymbol = 10;
    fs = symbolRate * samplesPerSymbol;
    timeSignal = upsample(qamSymbols, samplesPerSymbol);
    qamTimeSignal = filter(ones(1, samplesPerSymbol), 1, timeSignal);

    % Add AWGN noise
    snrValue = app.SNRdBEditField.Value;
    noisyQamTimeSignal = awgn(qamTimeSignal, snrValue, 'measured');
    t = (0:length(qamTimeSignal)-1) / fs;

    % Plot Power Spectral Density and Time-Domain Signals
    figure;
    subplot(2,1,1); pwelch(qamTimeSignal, [], [], [], fs, 'centered');
    title('PSD of Original 16-QAM Signal'); xlabel('Frequency (Hz)'); ylabel('Power/Frequency (dB/Hz)');
    subplot(2,1,2); pwelch(noisyQamTimeSignal, [], [], [], fs, 'centered');
    title(['PSD of 16-QAM with AWGN (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)'); ylabel('Power/Frequency (dB/Hz)');
     % Figure 2: Compare Time-Domain Signals (Original and With Noise)
    figure;
    subplot(2, 1, 1);
    plot(t, real(qamTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(qamTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title('Original 16-QAM Time-Domain Signal for coded signal');
    xlabel('Time (s)');
    ylabel('Amplitude');
    legend;
    grid on;

    subplot(2, 1, 2);
    plot(t, real(noisyQamTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(noisyQamTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title(['16-QAM Time-Domain Signal with AWGN for coded signal(SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)');
    ylabel('Amplitude');
    legend;
    grid on;


    % Plot Constellation Diagram
    figure;
    subplot(2,1,1); scatter(real(qamSymbols), imag(qamSymbols), 'filled'); title('Original 16-QAM');
    noisyQamSymbols = downsample(noisyQamTimeSignal, samplesPerSymbol);
    subplot(2,1,2); scatter(real(noisyQamSymbols), imag(noisyQamSymbols), 'filled');
    title(['16-QAM with AWGN (SNR = ' num2str(snrValue) ' dB)']);

    % Demodulate and display bits
    demodulatedSymbols = qamdemod(noisyQamSymbols, M, 'UnitAveragePower', true);
    demodulatedBits = de2bi(demodulatedSymbols, 4, 'left-msb')';
    app.demodulatedbits = demodulatedBits(:).';
    app.demodbitsTextArea.Value = num2str(app.demodulatedbits, '%d');
    app.modulatedbitswithcodingTextArea.Value = num2str(encodedBitstream, '%d');
end


   function QAM64(app)
    % Retrieve and prepare the bitstream for 64-QAM
    encodedBitstream = app.channelBits(:).';  % Ensure row vector
    if mod(length(encodedBitstream), 6) ~= 0
        encodedBitstream = [encodedBitstream, zeros(1, 6 - mod(length(encodedBitstream), 6))];
    end

    % Group bits into sextets, convert to decimal, and modulate to 64-QAM
    bitGroups = reshape(encodedBitstream, 6, []).';
    decimalSymbols = bi2de(bitGroups, 'left-msb');
    M = 64;
    qamSymbols = qammod(decimalSymbols, M, 'UnitAveragePower', true);

    % Generate continuous 64-QAM signal with pulse shaping
    symbolRate = 1e3;  
    samplesPerSymbol = 10;
    fs = symbolRate * samplesPerSymbol;
    timeSignal = upsample(qamSymbols, samplesPerSymbol);
    qamTimeSignal = filter(ones(1, samplesPerSymbol), 1, timeSignal);

    % Add AWGN noise
    snrValue = app.SNRdBEditField.Value;
    noisyQamTimeSignal = awgn(qamTimeSignal, snrValue, 'measured');
    t = (0:length(qamTimeSignal)-1) / fs;

    % Plot Power Spectral Density and Time-Domain Signals
    figure;
    subplot(2,1,1); pwelch(qamTimeSignal, [], [], [], fs, 'centered');
    title('PSD of Original 64-QAM Signal'); xlabel('Frequency (Hz)'); ylabel('Power/Frequency (dB/Hz)');
    subplot(2,1,2); pwelch(noisyQamTimeSignal, [], [], [], fs, 'centered');
    title(['PSD of 64-QAM with AWGN (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)'); ylabel('Power/Frequency (dB/Hz)');
    %plot time domain grpah
    figure;
    subplot(2, 1, 1);
    plot(t, real(qamTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(qamTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title('Original 64-QAM Time-Domain Signal of coded Signal');
    xlabel('Time (s)'); ylabel('Amplitude');
    legend; grid on;

    subplot(2, 1, 2);
    plot(t, real(noisyQamTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(noisyQamTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title(['Time-Domain Signal with AWGN of coded Signal (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)'); ylabel('Amplitude');
    legend; grid on;

    % Plot Constellation Diagram
    figure;
    subplot(2,1,1); scatter(real(qamSymbols), imag(qamSymbols), 'filled'); title('Original 64-QAM');
    noisyQamSymbols = downsample(noisyQamTimeSignal, samplesPerSymbol);
    subplot(2,1,2); scatter(real(noisyQamSymbols), imag(noisyQamSymbols), 'filled');
    title(['64-QAM with AWGN (SNR = ' num2str(snrValue) ' dB)']);

    % Demodulate and display bits
    demodulatedSymbols = qamdemod(noisyQamSymbols, M, 'UnitAveragePower', true);
    demodulatedBits = de2bi(demodulatedSymbols, 6, 'left-msb')';
    app.demodulatedbits = demodulatedBits(:).';
    app.demodbitsTextArea.Value = num2str(app.demodulatedbits, '%d');
    app.modulatedbitswithcodingTextArea.Value = num2str(encodedBitstream, '%d');
end


  function FSK16(app)
    % Retrieve and format the encoded bitstream
    encodedBitstream = app.channelBits(:).';  % Convert to row vector if needed

    % Pad the bitstream length to be a multiple of 4 (4 bits per 16-FSK symbol)
    if mod(length(encodedBitstream), 4) ~= 0
        encodedBitstream = [encodedBitstream, zeros(1, 4 - mod(length(encodedBitstream), 4))];
    end

    % Reshape bitstream into 4-bit groups and convert to decimal symbols
    bitGroups = reshape(encodedBitstream, 4, []).';
    decimalSymbols = bi2de(bitGroups, 'left-msb');

    % 16-FSK modulation parameters
    M = 16;
    symbolRate = 1e3;             % Symbols per second
    samplesPerSymbol = 100;       % Samples per symbol
    fs = symbolRate * samplesPerSymbol;  % Sampling frequency
    freqSpacing = 500;            % Frequency spacing between FSK tones

    % Generate modulated 16-FSK signal
    fskSignal = fskmod(decimalSymbols, M, freqSpacing, samplesPerSymbol, fs);

    % Add AWGN noise to the FSK signal
    snrValue = app.SNRdBEditField.Value;
    noisyFskSignal = awgn(fskSignal, snrValue, 'measured');

    % Define time vector for plots
    totalTime = (0:length(fskSignal)-1) / fs;

    % Plot Power Spectral Density for original and noisy signals
    figure;
    subplot(2, 1, 1);
    pwelch(fskSignal, [], [], [], fs, 'centered');
    title('Power Spectral Density of Original 16-FSK Signal with coding');
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');

    subplot(2, 1, 2);
    pwelch(noisyFskSignal, [], [], [], fs, 'centered');
    title(['Power Spectral Density with AWGN with coding (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');

    % Plot Time-Domain Signal for original and noisy signals
    figure;
    subplot(2, 1, 1);
    plot(totalTime, fskSignal);
    title('Original 16-FSK Time-Domain Signal with coding');
    xlabel('Time (s)');
    ylabel('Amplitude');
    grid on;

    subplot(2, 1, 2);
    plot(totalTime, noisyFskSignal);
    title(['Time-Domain Signal with AWGN with coding (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)');
    ylabel('Amplitude');
    grid on;
    % Generate original constellation diagram
    figure;
    subplot(2, 1, 1);
    % Convert symbols to complex points on unit circle
    originalPoints = exp(1i * 2 * pi * decimalSymbols / M);
    scatter(real(originalPoints), imag(originalPoints), 50, 'filled', 'b');
    grid on;
    title('Original 16-FSK Constellation Diagram with Coding');
    xlabel('In-phase');
    ylabel('Quadrature');
    axis([-1.5 1.5 -1.5 1.5]);
    
    % Generate noisy constellation diagram
    subplot(2, 1, 2);
    % Demodulate the noisy signal
    demodulatedSymbols = fskdemod(noisyFskSignal, M, freqSpacing, samplesPerSymbol, fs);
    % Convert demodulated symbols to complex points
    noisyPoints = exp(1i * 2 * pi * demodulatedSymbols / M);
    scatter(real(noisyPoints), imag(noisyPoints), 50, 'filled', 'r');
    grid on;
    title(['Noisy 16-FSK Constellation Diagram with Coding (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('In-phase');
    ylabel('Quadrature');
    axis([-1.5 1.5 -1.5 1.5]);

    % Demodulate the noisy FSK signal back to symbols
    demodulatedSymbols = fskdemod(noisyFskSignal, M, freqSpacing, samplesPerSymbol, fs);
    demodulatedBits = reshape(de2bi(demodulatedSymbols, 4, 'left-msb').', 1, []);

    % Update the app's UI text areas with the modulated and demodulated bitstreams
    app.demodulatedbits = demodulatedBits;
    app.demodbitsTextArea.Value = num2str(demodulatedBits, '%d');
    app.modulatedbitswithcodingTextArea.Value = num2str(encodedBitstream, '%d');
end


    function FSK64(app)
    % Retrieve the encoded bitstream
    encodedBitstream = app.channelBits;

    % Ensure that the bitstream is a row vector
    encodedBitstream = encodedBitstream(:).';  % Convert to row vector if needed

    % Ensure that the bitstream length is a multiple of 6 (6 bits per symbol for 64-FSK)
    if mod(length(encodedBitstream), 6) ~= 0
        encodedBitstream = [encodedBitstream, zeros(1, 6 - mod(length(encodedBitstream), 6))];
    end

    % Reshape the bitstream into groups of 6 bits
    bitGroups = reshape(encodedBitstream, 6, []).';  % Each row is now a group of 6 bits

    % Convert each group of 6 bits to decimal for modulation
    decimalSymbols = bi2de(bitGroups, 'left-msb');  % Map bits to integer symbols

    % Define parameters for 64-FSK
    M = 64;                         % 64-FSK modulation
    symbolRate = 1e3;               % Symbol rate (symbols per second)
    samplesPerSymbol = 100;         % Number of samples per symbol
    fs = symbolRate * samplesPerSymbol;  % Sampling frequency
    freqSpacing = 300;              % Frequency spacing between FSK tones

    % Generate 64-FSK modulated signal
    fskSignal = fskmod(decimalSymbols, M, freqSpacing, samplesPerSymbol, fs);

    % Add AWGN noise to the FSK signal
    snrValue = app.SNRdBEditField.Value;  % Set desired SNR in dB from UI
    noisyFskSignal = awgn(fskSignal, snrValue, 'measured');  % Add AWGN to the signal

    % Define time vector for plotting
    totalTime = (0:length(fskSignal)-1) / fs;

    % Plot Power Spectral Density (Original and With Noise)
    figure;
    subplot(2, 1, 1);
    pwelch(fskSignal, [], [], [], fs, 'centered');
    title('Power Spectral Density of Original 64-FSK Signal');
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');

    subplot(2, 1, 2);
    pwelch(noisyFskSignal, [], [], [], fs, 'centered');
    title(['Power Spectral Density of 64-FSK Signal with AWGN (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');

    % Plot Time-Domain Signals (Original and With Noise)
    figure;
    subplot(2, 1, 1);
    plot(totalTime, fskSignal);
    title('Original 64-FSK Time-Domain Signal');
    xlabel('Time (s)');
    ylabel('Amplitude');
    grid on;

    subplot(2, 1, 2);
    plot(totalTime, noisyFskSignal);
    title(['64-FSK Time-Domain Signal with AWGN (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)');
    ylabel('Amplitude');
    grid on;
    
     % Create constellation diagrams figure
    figure;
    
    % Generate original constellation diagram
    subplot(2, 1, 1);
    % Convert symbols to complex points on unit circle
    originalPoints = exp(1i * 2 * pi * decimalSymbols / M);
    scatter(real(originalPoints), imag(originalPoints), 30, 'filled', 'b');
    grid on;
    title('Original 64-FSK Constellation Diagram');
    xlabel('In-phase');
    ylabel('Quadrature');
    axis([-1.5 1.5 -1.5 1.5]);
    
    % Add circle to show ideal constellation points
    hold on;
    theta = linspace(0, 2*pi, 1000);
    plot(cos(theta), sin(theta), 'k--', 'LineWidth', 0.5);
    hold off;
    
    % Generate noisy constellation diagram
    subplot(2, 1, 2);
    % Demodulate the noisy signal
    demodulatedSymbols = fskdemod(noisyFskSignal, M, freqSpacing, samplesPerSymbol, fs);
    % Convert demodulated symbols to complex points
    noisyPoints = exp(1i * 2 * pi * demodulatedSymbols / M);
    scatter(real(noisyPoints), imag(noisyPoints), 30, 'filled', 'r');
    grid on;
    title(['Noisy 64-FSK Constellation Diagram (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('In-phase');
    ylabel('Quadrature');
    axis([-1.5 1.5 -1.5 1.5]);
    
    % Add circle to show ideal constellation points
    hold on;
    plot(cos(theta), sin(theta), 'k--', 'LineWidth', 0.5);
    hold off;
    

    % Demodulate the noisy FSK signal back to symbols
    demodulatedSymbols = fskdemod(noisyFskSignal, M, freqSpacing, samplesPerSymbol, fs);

    % Convert demodulated symbols to binary bits
    demodulatedBits = de2bi(demodulatedSymbols, 6, 'left-msb');  % Convert to binary groups of 6 bits
    demodulatedBits = demodulatedBits.';  % Transpose to column format
    demodulatedBits = demodulatedBits(:).';  % Reshape to a row vector
    app.demodulatedbits =demodulatedBits;

    % Display demodulated bits in the demodulatedBitsTextArea
    demodulatedBitsString = num2str(demodulatedBits, '%d');
    app.demodbitsTextArea.Value = demodulatedBitsString;

    % Convert modulated bits to a string for display
    bitString = num2str(encodedBitstream, '%d');  % Convert bits to a single string
    app.modulatedbitswithcodingTextArea.Value = bitString;
end


function hammingdecoder(app)
    % Retrieve demodulated bits
    demodulatedBits = app.demodulatedbits;

    % Adjust bit length to be a multiple of 7
    bitLength = length(demodulatedBits);
    if mod(bitLength, 7) ~= 0
        demodulatedBits = demodulatedBits(1:end - mod(bitLength, 7));
        disp('Note: Extra bits trimmed to fit 7-bit codewords.');
    end

    % Reshape bits into 7-bit codewords
    codewords = reshape(demodulatedBits, 7, []).';

    % Parity-check matrix for Hamming (7,4)
    H = [1 0 0 1 1 1 1;
         0 1 0 1 1 1 0;
         0 0 1 1 1 0 1];

    % Initialize storage for decoded bits
    decodedBits = [];

    % Decode each 7-bit codeword
    for i = 1:size(codewords, 1)
        codeword = codewords(i, :);

        % Calculate syndrome
        syndrome = mod(codeword * H.', 2);

        % Correct single-bit error if syndrome is non-zero
        if any(syndrome)
            errorPosition = bi2de(fliplr(syndrome), 'left-msb') + 1;
            if errorPosition <= 7
                codeword(errorPosition) = mod(codeword(errorPosition) + 1, 2);
            end
        end

        % Extract the original 4 data bits
        decodedBits = [decodedBits, codeword(1:4)];
    end

    % Update the app with decoded bits
    app.decodedbits = decodedBits;
    app.decodedbitsTextArea.Value = strjoin(string(decodedBits), '');
end


function BCH127decoder(app)
    % Get demodulated bits and ensure a column vector
    demodulatedBits = app.demodulatedbits(:);

    % Add padding if needed to make length a multiple of 127
    if mod(length(demodulatedBits), 127) ~= 0
        demodulatedBits = [demodulatedBits; zeros(127 - mod(length(demodulatedBits), 127), 1)];
    end

    % Reshape into 127-bit codewords
    codewords = reshape(demodulatedBits, 127, []).';

    % Initialize BCH decoder for (127, 64) code
    bchDecoder = comm.BCHDecoder('CodewordLength', 127, 'MessageLength', 64);

    % Decode each 127-bit codeword
    decodedBits = [];
    for i = 1:size(codewords, 1)
        [decodedMsg, ~] = step(bchDecoder, codewords(i, :).');
        decodedBits = [decodedBits; decodedMsg(:)'];
    end

    % Remove padding if present
    if app.paddingLength > 0
        decodedBits = decodedBits(1:end - app.paddingLength);
    end

    % Update and display decoded bits
    app.decodedbits = decodedBits(:).';
    app.decodedbitsTextArea.Value = strjoin(string(app.decodedbits), '');
end

function BCH63decoder(app)
    % Get demodulated bits and ensure a column vector
    demodulatedBits = app.demodulatedbits(:);

    % Add padding if needed to make length a multiple of 63
    if mod(length(demodulatedBits), 63) ~= 0
        demodulatedBits = [demodulatedBits; zeros(63 - mod(length(demodulatedBits), 63), 1)];
    end

    % Reshape into 63-bit codewords
    codewords = reshape(demodulatedBits, 63, []).';

    % Initialize BCH decoder for (63, 51) code
    bchDecoder = comm.BCHDecoder('CodewordLength', 63, 'MessageLength', 51);

    % Decode each 63-bit codeword
    decodedBits = [];
    for i = 1:size(codewords, 1)
        [decodedMsg, ~] = step(bchDecoder, codewords(i, :).');
        decodedBits = [decodedBits; decodedMsg(:)'];
    end

    % Remove padding if present
    if app.paddingLength > 0
        decodedBits = decodedBits(1:end - app.paddingLength);
    end

    % Update and display decoded bits
    app.decodedbits = decodedBits(:).';
    app.decodedbitsTextArea.Value = strjoin(string(app.decodedbits), '');
end


function convertBitsToImage(app)
    % Retrieve the bitstream and original dimensions
    binaryStream = app.decodedbits;
    imgh = app.imgHeight;
    imgw = app.imgWidth;
    imgc = app.imgChannels;

    % Ensure binaryStream is a character array of '0's and '1's
    if isnumeric(binaryStream)
        binaryStream = char(binaryStream + '0');  % Convert numeric 0s and 1s to '0' and '1' characters
    end

    % Calculate the exact number of bits needed for the image
    expectedBits = imgh * imgw * imgc * 8;
    actualBits = length(binaryStream);

    % Debugging statements to check bit lengths
    fprintf('Expected bit length: %d\n', expectedBits);
    fprintf('Actual bit length: %d\n', actualBits);

    % Trim or pad the bitstream to match the expected length
    if actualBits > expectedBits
        binaryStream = binaryStream(1:expectedBits);  % Trim extra bits
    elseif actualBits < expectedBits
        % Pad with '0's if bitstream is too short
        binaryStream = [binaryStream, repmat('0', 1, expectedBits - actualBits)];
    end

    % Convert bitstream back to bytes
    binaryImage = reshape(binaryStream, 8, []).'; % Reshape into bytes (8 bits per byte)
    pixelValues = uint8(bin2dec(binaryImage));    % Convert binary to decimal (uint8 pixels)

    % Reshape into original image dimensions
    reconstructedImg = reshape(pixelValues, imgh, imgw, imgc);

    % Display the reconstructed image
    imshow(reconstructedImg);
    title('Reconstructed Image');
end


function convertBitsToText(app)
    % Retrieve the decoded bits
    decodedBits = app.decodedbits;

    % Ensure the decodedBits is a numeric array of 0s and 1s
    if ischar(decodedBits)
        decodedBits = decodedBits - '0'; % Convert '0' and '1' characters to numeric 0s and 1s
    end

    % Ensure the length of decodedBits is a multiple of 8 for ASCII characters
    bitLength = length(decodedBits);
    decodedBits = decodedBits(1:bitLength - mod(bitLength, 8)); % Truncate extra bits if any

    % Reshape bits into groups of 8
    binaryCharacters = reshape(decodedBits, 8, [])';

    % Convert each 8-bit binary group to a character
    textData = char(bin2dec(num2str(binaryCharacters)))';

    % Display or save the decoded text
    [file, path] = uiputfile('*.txt', 'Save Decoded Text As');
    if file ~= 0
        fid = fopen(fullfile(path, file), 'w');
        fwrite(fid, textData, 'char');
        fclose(fid);
        disp(['Decoded text saved to ', fullfile(path, file)]);
    end
end


function convertBitsToAudio(app)
    % Retrieve the decoded bits from the app
    decodedBits = app.decodedbits; % Assuming this holds the final decoded bitstream

    % Ensure the decodedBits is a character array
    if isstring(decodedBits) || isnumeric(decodedBits)
        decodedBits = char(decodedBits + '0'); % Convert numeric to character array
    end

    % Check the length of decoded bits
    numBits = length(decodedBits);
    disp(['Length of decoded bits: ', num2str(numBits)]);

    % Pad the bitstream if necessary
    if mod(numBits, 8) ~= 0
        paddingLength = 8 - mod(numBits, 8);
        decodedBits = [decodedBits, repmat('0', 1, paddingLength)]; % Pad with zeros
    end

    % Reshape the bitstream to 8-bit segments
    decodedBitsMatrix = reshape(decodedBits, 8, []).';

    % Convert 8-bit segments to decimal values
    audioDataInt = uint8(bin2dec(decodedBitsMatrix));

    % Scale back to the original audio range [0, 1] to [-1, 1]
    audioData = double(audioDataInt) / 255; % Convert to range [0, 1]
    audioData = (audioData * 2) - 1; % Shift to range [-1, 1]

    % Define the sampling rate (you may need to adjust this)
    fs = 44100; % Default sampling rate (should match the original)

    % Play the reconstructed audio
    sound(audioData, fs);

    % Save the audio to a file (optional)
    audiowrite('reconstructed_audio.wav', audioData, fs);

    % Display completion message
    disp('Audio has been reconstructed and played back.');
end

function QPSKuncoded(app) 
    % Retrieve and validate the encoded bitstream
    rawBitstream = app.generatedBits(:); % Ensure it’s a column vector

    % Convert character array to numeric (if needed)
    if ischar(rawBitstream) || isstring(rawBitstream)
        encodedBitstream = rawBitstream - '0'; % Convert '0', '1' to 0, 1
    else
        encodedBitstream = rawBitstream; % Assume it's already numeric
    end

    % Validate bitstream contains only binary values
    if any(encodedBitstream ~= 0 & encodedBitstream ~= 1)
        error('Bitstream must only contain binary values (0 and 1).');
    end

    % Pad if bitstream length is odd (QPSK requires 2 bits per symbol)
    if mod(length(encodedBitstream), 2) ~= 0
        encodedBitstream = [encodedBitstream; 0];
    end

    % Convert bit pairs to decimal symbols
    bitPairs = reshape(encodedBitstream, 2, []).'; % 2 columns, row-wise pairing
    decimalSymbols = bi2de(bitPairs, 'left-msb'); % Convert binary pairs to decimal

    % QPSK modulation
    M = 4; % QPSK uses 4 symbols
    qpskSymbols = pskmod(decimalSymbols, M, 0, 'gray');

    % Generate time-domain QPSK signal with pulse shaping
    symbolRate = 1e3; % 1 kHz symbol rate
    samplesPerSymbol = 10;
    fs = symbolRate * samplesPerSymbol; % Sampling frequency
    timeSignal = upsample(qpskSymbols, samplesPerSymbol); % Zero padding
    pulseShape = ones(1, samplesPerSymbol); % Rectangular pulse
    qpskTimeSignal = filter(pulseShape, 1, timeSignal); % Apply pulse shaping

    % Add AWGN noise to QPSK signal
    snrValue = app.SNRdBEditField.Value;
    noisyQpskTimeSignal = awgn(qpskTimeSignal, snrValue, 'measured');

    % Time vector for plotting
    t = (0:length(qpskTimeSignal)-1) / fs;

    % Plot Power Spectral Density
    figure;
    subplot(2, 1, 1);
    pwelch(qpskTimeSignal, [], [], [], fs, 'centered');
    title('Power Spectral Density of Uncoded Original QPSK Signal');
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');

    subplot(2, 1, 2);
    pwelch(noisyQpskTimeSignal, [], [], [], fs, 'centered');
    title(['Power Spectral Density with AWGN of Uncoded Signal (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');

    % Plot Time-Domain Signals
    figure;
    subplot(2, 1, 1);
    plot(t, real(qpskTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(qpskTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title('Original QPSK Time-Domain Signal of Uncoded Signal');
    xlabel('Time (s)');
    ylabel('Amplitude');
    legend;
    grid on;

    subplot(2, 1, 2);
    plot(t, real(noisyQpskTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(noisyQpskTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title(['Time-Domain Signal with AWGN of Uncoded Signal(SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)');
    ylabel('Amplitude');
    legend;
    grid on;

    % Plot Constellation Diagrams
    figure;
    subplot(2, 1, 1);
    scatter(real(qpskSymbols), imag(qpskSymbols), 'filled');
    grid on;
    title('Original QPSK Constellation of Uncoded signal');

    noisyQpskSymbols = downsample(noisyQpskTimeSignal, samplesPerSymbol);
    subplot(2, 1, 2);
    scatter(real(noisyQpskSymbols), imag(noisyQpskSymbols), 'filled');
    grid on;
    title(['Constellation with AWGN of Uncoded signal(SNR = ' num2str(snrValue) ' dB)']);

   % Demodulate QPSK
    demodulatedSymbols = pskdemod(noisyQpskSymbols, M, 0, 'gray');
    demodulatedBits = de2bi(demodulatedSymbols, 2, 'left-msb');
    % Convert to a single column vector and store
    app.uncodeddemodulatedbits = demodulatedBits(:);  % Store as a single column
    % Convert to string for display
    demodulatedBitsString = num2str(demodulatedBits(:).', '%d');
    app.demodBitswithoutcodingTextArea.Value = {demodulatedBitsString};

    % Display original modulated bitstream
    bitString = num2str(encodedBitstream, '%d');
    app.modulatedbitswithoutcodingTextArea.Value = {bitString};

end

function QAM16uncoded(app)
    % Retrieve and validate bitstream for 16-QAM
   % Input handling based on dropdown selection
if strcmp(app.UserInputDropDown.Value, 'Text')
    encodedBitstream = app.generatedBits(:).'; % Direct transpose for text input
else
    encodedBitstream = str2double(cellstr(app.generatedBits(:))).'; % Convert to numeric for other inputs
end
    
    % Check for non-binary values in the bitstream and raise an error if found
    if any(~ismember(encodedBitstream, [0, 1]))
        error('Bitstream contains non-binary values.');
    end

    % Pad bitstream if needed to ensure it has a length that’s a multiple of 4
    if mod(length(encodedBitstream), 4) ~= 0
        encodedBitstream = [encodedBitstream, zeros(1, 4 - mod(length(encodedBitstream), 4))];
    end

    % Group bits into quads, convert to decimal, and modulate to 16-QAM
    bitQuads = reshape(encodedBitstream, 4, []).';
    decimalSymbols = bi2de(bitQuads, 'left-msb');
    M = 16;
    qamSymbols = qammod(decimalSymbols, M, 'UnitAveragePower', true);

    % Generate continuous 16-QAM signal with pulse shaping
    symbolRate = 1e3;
    samplesPerSymbol = 10;
    fs = symbolRate * samplesPerSymbol;
    timeSignal = upsample(qamSymbols, samplesPerSymbol);
    qamTimeSignal = filter(ones(1, samplesPerSymbol), 1, timeSignal);

    % Add AWGN noise
    snrValue = app.SNRdBEditField.Value;
    noisyQamTimeSignal = awgn(qamTimeSignal, snrValue, 'measured');
    t = (0:length(qamTimeSignal)-1) / fs;

    % Plot Power Spectral Density
    figure;
    subplot(2,1,1);
    pwelch(qamTimeSignal, [], [], [], fs, 'centered');
    title('Power Spectral Density of Uncoded Original 16-QAM Signal');
    xlabel('Frequency (Hz)'); ylabel('Power/Frequency (dB/Hz)');

    subplot(2,1,2);
    pwelch(noisyQamTimeSignal, [], [], [], fs, 'centered');
    title(['Power Spectral Density of Uncoded Signal with AWGN (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)'); ylabel('Power/Frequency (dB/Hz)');

    % Plot Time-Domain Signals
    figure;
    subplot(2, 1, 1);
    plot(t, real(qamTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(qamTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title('Original 16-QAM Time-Domain Signal of Uncoded Signal');
    xlabel('Time (s)'); ylabel('Amplitude');
    legend; grid on;

    subplot(2, 1, 2);
    plot(t, real(noisyQamTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(noisyQamTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title(['Time-Domain Signal with AWGN of Uncoded Signal (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)'); ylabel('Amplitude');
    legend; grid on;

    % Plot Constellation Diagrams
    figure;
    subplot(2,1,1);
    scatter(real(qamSymbols), imag(qamSymbols), 'filled');
    title('Original 16-QAM Constellation of Uncoded Signal');
    grid on;

    noisyQamSymbols = downsample(noisyQamTimeSignal, samplesPerSymbol);
    subplot(2,1,2);
    scatter(real(noisyQamSymbols), imag(noisyQamSymbols), 'filled');
    title(['Constellation with AWGN of Uncoded Signal (SNR = ' num2str(snrValue) ' dB)']);
    grid on;

    % Demodulate and display bits
    demodulatedSymbols = qamdemod(noisyQamSymbols, M, 'UnitAveragePower', true);
    demodulatedBits = de2bi(demodulatedSymbols, 4, 'left-msb')';
    app.uncodeddemodulatedbits = demodulatedBits(:).';
    app.demodBitswithoutcodingTextArea.Value = num2str(app.uncodeddemodulatedbits, '%d');  % Display demodulated bits
    app.modulatedbitswithoutcodingTextArea.Value = num2str(encodedBitstream, '%d');  % Display original encoded bitstream
end

function QAM64uncoded(app)
    % Retrieve and validate bitstream for 64-QAM
    if strcmp(app.UserInputDropDown.Value, 'Text')
    encodedBitstream = app.generatedBits(:).'; % Direct transpose for text input
else
    encodedBitstream = str2double(cellstr(app.generatedBits(:))).'; % Convert to numeric for other inputs
end
    % Check for non-binary values in the bitstream and raise an error if found
    if any(~ismember(encodedBitstream, [0, 1]))
        error('Bitstream contains non-binary values.');
    end

    % Pad bitstream if needed to ensure it has a length that’s a multiple of 6
    if mod(length(encodedBitstream), 6) ~= 0
        encodedBitstream = [encodedBitstream, zeros(1, 6 - mod(length(encodedBitstream), 6))];
    end

    % Group bits into sextets, convert to decimal, and modulate to 64-QAM
    bitSextets = reshape(encodedBitstream, 6, []).';
    decimalSymbols = bi2de(bitSextets, 'left-msb');
    M = 64;
    qamSymbols = qammod(decimalSymbols, M, 'UnitAveragePower', true);

    % Generate continuous 64-QAM signal with pulse shaping
    symbolRate = 1e3;
    samplesPerSymbol = 10;
    fs = symbolRate * samplesPerSymbol;
    timeSignal = upsample(qamSymbols, samplesPerSymbol);
    qamTimeSignal = filter(ones(1, samplesPerSymbol), 1, timeSignal);

    % Add AWGN noise
    snrValue = app.SNRdBEditField.Value;
    noisyQamTimeSignal = awgn(qamTimeSignal, snrValue, 'measured');
    t = (0:length(qamTimeSignal)-1) / fs;

    % Plot Power Spectral Density
    figure;
    subplot(2,1,1);
    pwelch(qamTimeSignal, [], [], [], fs, 'centered');
    title('Power Spectral Density of Uncoded Original 64-QAM Signal');
    xlabel('Frequency (Hz)'); ylabel('Power/Frequency (dB/Hz)');

    subplot(2,1,2);
    pwelch(noisyQamTimeSignal, [], [], [], fs, 'centered');
    title(['Power Spectral Density of Uncoded Signal with AWGN (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)'); ylabel('Power/Frequency (dB/Hz)');

    % Plot Time-Domain Signals
    figure;
    subplot(2, 1, 1);
    plot(t, real(qamTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(qamTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title('Original 64-QAM Time-Domain Signal of Uncoded Signal');
    xlabel('Time (s)'); ylabel('Amplitude');
    legend; grid on;

    subplot(2, 1, 2);
    plot(t, real(noisyQamTimeSignal), 'b', 'DisplayName', 'In-phase');
    hold on;
    plot(t, imag(noisyQamTimeSignal), 'r', 'DisplayName', 'Quadrature');
    title(['Time-Domain Signal with AWGN of Uncoded Signal (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)'); ylabel('Amplitude');
    legend; grid on;

    % Plot Constellation Diagrams
    figure;
    subplot(2,1,1);
    scatter(real(qamSymbols), imag(qamSymbols), 'filled');
    title('Original 64-QAM Constellation of Uncoded Signal');
    grid on;

    noisyQamSymbols = downsample(noisyQamTimeSignal, samplesPerSymbol);
    subplot(2,1,2);
    scatter(real(noisyQamSymbols), imag(noisyQamSymbols), 'filled');
    title(['Constellation with AWGN of Uncoded Signal (SNR = ' num2str(snrValue) ' dB)']);
    grid on;


    % Demodulate and display bits
    demodulatedSymbols = qamdemod(noisyQamSymbols, M, 'UnitAveragePower', true);
    demodulatedBits = de2bi(demodulatedSymbols, 6, 'left-msb')';
    app.uncodeddemodulatedbits = demodulatedBits(:).';
    app.demodBitswithoutcodingTextArea.Value = num2str(app.uncodeddemodulatedbits, '%d');  % Display demodulated bits
    app.modulatedbitswithoutcodingTextArea.Value = num2str(encodedBitstream, '%d');  % Display original encoded bitstream
end

function FSK16uncoded(app)
    % Retrieve and validate the bitstream
    if strcmp(app.UserInputDropDown.Value, 'Text')
    encodedBitstream = app.generatedBits(:).'; % Direct transpose for text input
    else
    encodedBitstream = str2double(cellstr(app.generatedBits(:))).'; % Convert to numeric for other inputs
    end
    
    % Check for non-binary values in the bitstream and raise an error if found
    if any(~ismember(encodedBitstream, [0, 1]))
        error('Bitstream contains non-binary values.');
    end
    
    % Pad the bitstream length to be a multiple of 4 (4 bits per 16-FSK symbol)
    if mod(length(encodedBitstream), 4) ~= 0
        encodedBitstream = [encodedBitstream, zeros(1, 4 - mod(length(encodedBitstream), 4))];
    end
    
    % Reshape bitstream into 4-bit groups and convert to decimal symbols
    bitGroups = reshape(encodedBitstream, 4, []).';
    decimalSymbols = bi2de(bitGroups, 'left-msb');
    
    % 16-FSK modulation parameters
    M = 16;
    symbolRate = 1e3; % Symbols per second
    samplesPerSymbol = 100; % Samples per symbol
    fs = symbolRate * samplesPerSymbol; % Sampling frequency
    freqSpacing = 500; % Frequency spacing between FSK tones
    
    % Generate modulated 16-FSK signal
    fskSignal = fskmod(decimalSymbols, M, freqSpacing, samplesPerSymbol, fs);
    
    % Add AWGN noise to the FSK signal
    snrValue = app.SNRdBEditField.Value;
    noisyFskSignal = awgn(fskSignal, snrValue, 'measured');
    
    % Define time vector for plots
    totalTime = (0:length(fskSignal)-1) / fs;
    
    % Plot Power Spectral Density for original and noisy signals
    figure;
    subplot(2, 1, 1);
    pwelch(fskSignal, [], [], [], fs, 'centered');
    title('Power Spectral Density of Uncoded Original 16-FSK Signal');
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');
    
    subplot(2, 1, 2);
    pwelch(noisyFskSignal, [], [], [], fs, 'centered');
    title(['Power Spectral Density with AWGN of Uncoded Signal (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');
    
    % Plot Time-Domain Signal for original and noisy signals
    figure;
    subplot(2, 1, 1);
    plot(totalTime, fskSignal);
    title('Original 16-FSK Time-Domain Signal of Uncoded Signal');
    xlabel('Time (s)');
    ylabel('Amplitude');
    grid on;
    
    subplot(2, 1, 2);
    plot(totalTime, noisyFskSignal);
    title(['Time-Domain Signal with AWGN of Uncoded Signal (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)');
    ylabel('Amplitude');
    grid on;
    
    % Create constellation diagrams figure
    figure;
    
    % Generate original constellation diagram
    subplot(2, 1, 1);
    % Convert symbols to complex points on unit circle
    originalPoints = exp(1i * 2 * pi * decimalSymbols / M);
    scatter(real(originalPoints), imag(originalPoints), 50, 'filled', 'b');
    grid on;
    title('Original 16-FSK Constellation Diagram');
    xlabel('In-phase');
    ylabel('Quadrature');
    axis([-1.5 1.5 -1.5 1.5]);
    
    % Generate noisy constellation diagram
    subplot(2, 1, 2);
    % Demodulate the noisy signal
    demodulatedSymbols = fskdemod(noisyFskSignal, M, freqSpacing, samplesPerSymbol, fs);
    % Convert demodulated symbols to complex points
    noisyPoints = exp(1i * 2 * pi * demodulatedSymbols / M);
    scatter(real(noisyPoints), imag(noisyPoints), 50, 'filled', 'r');
    grid on;
    title(['Noisy 16-FSK Constellation Diagram (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('In-phase');
    ylabel('Quadrature');
    axis([-1.5 1.5 -1.5 1.5]);
    
    % Convert demodulated symbols back to bits
    demodulatedBits = reshape(de2bi(demodulatedSymbols, 4, 'left-msb').', 1, []);
    
    % Update the app's UI text areas
    app.uncodeddemodulatedbits = demodulatedBits;
    app.demodBitswithoutcodingTextArea.Value = num2str(demodulatedBits, '%d'); % Display demodulated bits
    app.modulatedbitswithoutcodingTextArea.Value = num2str(encodedBitstream, '%d'); % Display original bitstream
end

function FSK64uncoded(app)
    % Retrieve and validate the bitstream
    if strcmp(app.UserInputDropDown.Value, 'Text')
    encodedBitstream = app.generatedBits(:).'; % Direct transpose for text input
else
    encodedBitstream = str2double(cellstr(app.generatedBits(:))).'; % Convert to numeric for other inputs
end
    
    % Check for non-binary values in the bitstream and raise an error if found
    if any(~ismember(encodedBitstream, [0, 1]))
        error('Bitstream contains non-binary values.');
    end
    
    % Pad the bitstream length to be a multiple of 6 (6 bits per 64-FSK symbol)
    if mod(length(encodedBitstream), 6) ~= 0
        encodedBitstream = [encodedBitstream, zeros(1, 6 - mod(length(encodedBitstream), 6))];
    end
    
    % Reshape bitstream into 6-bit groups and convert to decimal symbols
    bitGroups = reshape(encodedBitstream, 6, []).';
    decimalSymbols = bi2de(bitGroups, 'left-msb');
    
    % 64-FSK modulation parameters
    M = 64;  % Number of frequency symbols
    symbolRate = 1e3; % Symbols per second
    samplesPerSymbol = 100; % Samples per symbol
    fs = symbolRate * samplesPerSymbol; % Sampling frequency
    freqSpacing = 500; % Frequency spacing between FSK tones
    
    % Generate modulated 64-FSK signal
    fskSignal = fskmod(decimalSymbols, M, freqSpacing, samplesPerSymbol, fs);
    
    % Add AWGN noise to the FSK signal
    snrValue = app.SNRdBEditField.Value;
    noisyFskSignal = awgn(fskSignal, snrValue, 'measured');
    
    % Define time vector for plots
    totalTime = (0:length(fskSignal)-1) / fs;
    
    % Plot Power Spectral Density for original and noisy signals
    figure;
    subplot(2, 1, 1);
    pwelch(fskSignal, [], [], [], fs, 'centered');
    title('Power Spectral Density of Uncoded Original 64-FSK Signal');
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');
    
    subplot(2, 1, 2);
    pwelch(noisyFskSignal, [], [], [], fs, 'centered');
    title(['Power Spectral Density with AWGN of Uncoded Signal (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Frequency (Hz)');
    ylabel('Power/Frequency (dB/Hz)');
    
    % Plot Time-Domain Signal for original and noisy signals
    figure;
    subplot(2, 1, 1);
    plot(totalTime, fskSignal);
    title('Original 64-FSK Time-Domain Signal of Uncoded Signal');
    xlabel('Time (s)');
    ylabel('Amplitude');
    grid on;
    
    subplot(2, 1, 2);
    plot(totalTime, noisyFskSignal);
    title(['Time-Domain Signal with AWGN of Uncoded Signal (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('Time (s)');
    ylabel('Amplitude');
    grid on;
    
    % Create constellation diagrams figure
    figure;
    
    % Generate original constellation diagram
    subplot(2, 1, 1);
    % Convert symbols to complex points on unit circle
    originalPoints = exp(1i * 2 * pi * decimalSymbols / M);
    scatter(real(originalPoints), imag(originalPoints), 30, 'filled', 'b');
    grid on;
    title('Original 64-FSK Constellation Diagram');
    xlabel('In-phase');
    ylabel('Quadrature');
    axis([-1.5 1.5 -1.5 1.5]);
    
    % Add circle to show ideal constellation points
    hold on;
    theta = linspace(0, 2*pi, 1000);
    plot(cos(theta), sin(theta), 'k--', 'LineWidth', 0.5);
    hold off;
    
    % Generate noisy constellation diagram
    subplot(2, 1, 2);
    % Demodulate the noisy signal
    demodulatedSymbols = fskdemod(noisyFskSignal, M, freqSpacing, samplesPerSymbol, fs);
    % Convert demodulated symbols to complex points
    noisyPoints = exp(1i * 2 * pi * demodulatedSymbols / M);
    scatter(real(noisyPoints), imag(noisyPoints), 30, 'filled', 'r');
    grid on;
    title(['Noisy 64-FSK Constellation Diagram (SNR = ' num2str(snrValue) ' dB)']);
    xlabel('In-phase');
    ylabel('Quadrature');
    axis([-1.5 1.5 -1.5 1.5]);
    
    % Add circle to show ideal constellation points
    hold on;
    plot(cos(theta), sin(theta), 'k--', 'LineWidth', 0.5);
    hold off;
    
    % Convert demodulated symbols back to bits
    demodulatedBits = reshape(de2bi(demodulatedSymbols, 6, 'left-msb').', 1, []);
    
    % Update the app's UI text areas
    app.uncodeddemodulatedbits = demodulatedBits;
    app.demodBitswithoutcodingTextArea.Value = num2str(demodulatedBits, '%d'); % Display demodulated bits
    app.modulatedbitswithoutcodingTextArea.Value = num2str(encodedBitstream, '%d'); % Display original bitstream
end

function convertBitsToImageuncoded(app)
    % Retrieve the bitstream and original dimensions
    binaryStream = app.uncodeddemodulatedbits;
    imgh = app.imgHeight;
    imgw = app.imgWidth;
    imgc = app.imgChannels;

    % Ensure binaryStream is a character array of '0's and '1's
    if isnumeric(binaryStream)
        binaryStream = char(binaryStream + '0');  % Convert numeric 0s and 1s to '0' and '1' characters
    end

    % Calculate the exact number of bits needed for the image
    expectedBits = imgh * imgw * imgc * 8;
    actualBits = length(binaryStream);

    % Debugging statements to check bit lengths
    fprintf('Expected bit length: %d\n', expectedBits);
    fprintf('Actual bit length: %d\n', actualBits);

    % Trim or pad the bitstream to match the expected length
    if actualBits > expectedBits
        binaryStream = binaryStream(1:expectedBits);  % Trim extra bits
    elseif actualBits < expectedBits
        % Pad with '0's if bitstream is too short
        binaryStream = [binaryStream, repmat('0', 1, expectedBits - actualBits)];
    end

    % Convert bitstream back to bytes
    binaryImage = reshape(binaryStream, 8, []).'; % Reshape into bytes (8 bits per byte)
    pixelValues = uint8(bin2dec(binaryImage));    % Convert binary to decimal (uint8 pixels)

    % Reshape into original image dimensions
    reconstructedImg = reshape(pixelValues, imgh, imgw, imgc);

    % Display the reconstructed image
    imshow(reconstructedImg);
    title('Reconstructed Image of Uncoded Signal');
end


function convertBitsToTextuncoded(app)
    % Retrieve the decoded bits
    decodedBits = app.uncodeddemodulatedbits; % Assume this is a property in your app

    % Ensure the decodedBits is a numeric array of 0s and 1s
    if ischar(decodedBits)
        decodedBits = decodedBits - '0'; % Convert '0' and '1' characters to numeric 0s and 1s
    elseif ~isnumeric(decodedBits)
        error('Decoded bits must be numeric or a binary string.');
    end

    % Ensure the length of decodedBits is a multiple of 8 for ASCII characters
    bitLength = length(decodedBits);
    if mod(bitLength, 8) ~= 0
        warning('Decoded bits length is not a multiple of 8. Truncating extra bits.');
        decodedBits = decodedBits(1:bitLength - mod(bitLength, 8)); % Truncate extra bits
    end

    % Reshape bits into groups of 8
    binaryCharacters = reshape(decodedBits, 8, [])'; % Each row is an 8-bit binary number

    % Convert each 8-bit binary group to a character
    textData = char(bin2dec(num2str(binaryCharacters)))'; % Convert binary to ASCII text

    % Prompt user to save the decoded text
    [file, path] = uiputfile('*.txt', 'Save Decoded Text As');
    if file ~= 0
        filepath = fullfile(path, file);
        fid = fopen(filepath, 'w');
        fwrite(fid, textData, 'char'); % Write the ASCII text into the file
        fclose(fid);
        disp(['Decoded text saved to ', filepath]);
    else
        disp('File save canceled. Decoded text is:');
        disp(textData); % Display text if not saved
    end

    % Verify the saved text by regenerating it back from ASCII
    regenerateTextFromFile(filepath);
end

function regenerateTextFromFile(filepath)
    % Read the binary data from the saved file
    if exist(filepath, 'file') == 2
        fid = fopen(filepath, 'r');
        fileData = fread(fid, '*char')'; % Read the file content as characters
        fclose(fid);

        % Convert ASCII text back to binary representation
        binaryRepresentation = reshape(dec2bin(fileData, 8)', 1, []); % Get binary as a single string

        disp('Regenerated binary data from the text file:');
        disp(binaryRepresentation);
    else
        disp('File does not exist. Cannot regenerate text.');
    end
end


function convertBitsToAudiouncoded(app)
    % Retrieve the decoded bits from the app
    decodedBits = app.uncodeddemodulatedbits; % Assuming this holds the final decoded bitstream

    % Ensure the decodedBits is a character array
    if isstring(decodedBits) || isnumeric(decodedBits)
        decodedBits = char(decodedBits + '0'); % Convert numeric to character array
    end

    % Reshape the bitstream to 8-bit segments
    numBits = length(decodedBits);
    if mod(numBits, 8) ~= 0
        error('Bitstream length is not a multiple of 8');
    end
    decodedBitsMatrix = reshape(decodedBits, 8, []).';

    % Convert 8-bit segments to decimal values
    audioDataInt = uint8(bin2dec(decodedBitsMatrix));

    % Scale back to the original audio range [0, 1] to [-1, 1]
    audioData = double(audioDataInt) / 255; % Convert to range [0, 1]
    audioData = (audioData * 2) - 1; % Shift to range [-1, 1]

    % Define the sampling rate (you may need to adjust this)
    fs = 44100; % Default sampling rate (should match the original)

    % Play the reconstructed audio
    sound(audioData, fs);

    % Save the audio to a file (optional)
    audiowrite('reconstructed_audiouncoded.wav', audioData, fs);

    % Display completion message
    disp('Audio of uncoded signal has been reconstructed and played back.');
end


function plotBERCurve(app)

EbNo = (0:30)';

berQ = berawgn(EbNo,'psk',4,'nondiff');
berF2 = berawgn(EbNo,'fsk',64,'coherent');
berF = berawgn(EbNo,'fsk',16,'coherent');
berqam = berawgn(EbNo,'qam',16);
berqam2 = berawgn(EbNo,'qam',64);

semilogy(EbNo,[berQ berF2 berF berqam berqam2 ])
xlabel('Eb/No (dB)')
ylabel('BER')
legend('QPSK','64 FSK','16 FSK','16 QAM', '64 QAM')
title("Theoretical Bit Error Rate")
grid on;
ylim([10^-5,1]);

end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: generateButton
        function generateButtonPushed(app, event)
            value = app.UserInputDropDown.Value;
            switch value
                case 'Random bits'
                    app.generateRandomBits();
                case 'Text'
                    app.convertTextToBits();
                case 'Image'
                    app.convertImageToBits();
                case 'Audio'
                    app.convertAudioToBits();
            end
        end

        % Button pushed function: encodeButton
        function encodeButtonPushed(app, event)
            value = app.ChannelCodeDropDown.Value;
            switch value
                case '(7,4) hamming'
                    app.hammingCode();
                case 'BCH (127,64)'
                    app.BCH127();
                case 'BCH (63,51)'
                    app.BCH63();
            end
        end

        % Button pushed function: modulateButton
        function modulateButtonPushed(app, event)
            value =app.DropDown.Value;
            switch value 
                case 'QPSK'
                    app.QPSK();
                case '16 QAM'
                    app.QAM16();
                case '64 QAM'
                    app.QAM64();
                case '16 FSK'
                    app.FSK16();
                case '64 FSK'
                    app.FSK64();
            end         
        end

        % Button pushed function: decodeButton
        function decodeButtonPushed(app, event)
            value = app.ChannelCodeDropDown.Value;
            switch value 
                case '(7,4) hamming'
                        app.hammingdecoder();
                case 'BCH (127,64)'
                    app.BCH127decoder();
                case 'BCH (63,51)'
                    app.BCH63decoder();
            end
        end

        % Button pushed function: plotBERButton
        function plotBERButtonPushed(app, event)
                app.plotBERCurve();
        end

        % Button pushed function: retrieveButton
        function retrieveButtonPushed(app, event)
            value = app.UserInputDropDown.Value;
            switch value
                case 'Random bits'
                    app.generateRandomBits();
                case 'Text'
                    app.convertBitsToText();
                case 'Image'
                    app.convertBitsToImage();
                case 'Audio'
                    app.convertBitsToAudio();
            end
            
        end

        % Button pushed function: modulateuncodedButton
        function modulateuncodedButtonPushed(app, event)
            value =app.DropDown.Value;
            switch value 
                case 'QPSK'
                    app.QPSKuncoded();
                case '16 QAM'
                    app.QAM16uncoded();
                case '64 QAM'
                    app.QAM64uncoded();
                case '16 FSK'
                    app.FSK16uncoded();
                case '64 FSK'
                    app.FSK64uncoded();
            end         
        end

        % Button pushed function: retrieveuncodedButton
        function retrieveuncodedButtonPushed(app, event)
             value = app.UserInputDropDown.Value;
            switch value
                case 'Random bits'
                    app.generateRandomBitsuncoded();
                case 'Text'
                    app.convertBitsToTextuncoded();
                case 'Image'
                    app.convertBitsToImageuncoded();
                case 'Audio'
                    app.convertBitsToAudiouncoded();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UserInputDropDownLabel = uilabel(app.UIFigure);
            app.UserInputDropDownLabel.Text = 'User Input';
            app.UserInputDropDown = uidropdown(app.UIFigure);
            app.UserInputDropDown.Items = {'Random bits', 'Text', 'Image', 'Audio'};
            app.UserInputDropDown.Value = 'Random bits';
            app.generateButton = uibutton(app.UIFigure, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generateButton.Text = 'generate';
            app.bitsgeneratedTextAreaLabel = uilabel(app.UIFigure);
            app.bitsgeneratedTextAreaLabel.Text = 'bits generated';
            app.bitsgeneratedTextArea = uitextarea(app.UIFigure);
            app.ChannelCodeDropDownLabel = uilabel(app.UIFigure);
            app.ChannelCodeDropDownLabel.Text = 'Channel Code';
            app.ChannelCodeDropDown = uidropdown(app.UIFigure);
            app.ChannelCodeDropDown.Items = {'(7,4) hamming', 'BCH (127,64)', 'BCH (63,51)'};
            app.ChannelCodeDropDown.Value = '(7,4) hamming';
            app.encodeButton = uibutton(app.UIFigure, 'push');
            app.encodeButton.ButtonPushedFcn = createCallbackFcn(app, @encodeButtonPushed, true);
            app.encodeButton.Text = 'encode';
            app.channelencodedbitsLabel = uilabel(app.UIFigure);
            app.channelencodedbitsLabel.Text = {'channel '; 'encoded bits'};
            app.channelencodedbitsTextArea = uitextarea(app.UIFigure);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'QPSK', '16 QAM', '64 QAM', '16 FSK', '64 FSK'};
            app.DropDown.Value = 'QPSK';
            app.modulateButton = uibutton(app.UIFigure, 'push');
            app.modulateButton.ButtonPushedFcn = createCallbackFcn(app, @modulateButtonPushed, true);
            app.modulateButton.Text = 'modulate';
            app.modulatedbitswithcodingTextAreaLabel = uilabel(app.UIFigure);
            app.modulatedbitswithcodingTextAreaLabel.Text = {'modulated '; 'bits with coding'};
            app.modulatedbitswithcodingTextArea = uitextarea(app.UIFigure);
            app.SNRdBEditFieldLabel = uilabel(app.UIFigure);
            app.SNRdBEditFieldLabel.Text = 'SNR(dB)';
            app.SNRdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.demodbitsTextAreaLabel = uilabel(app.UIFigure);
            app.demodbitsTextAreaLabel.Text = 'demod bits';
            app.demodbitsTextArea = uitextarea(app.UIFigure);
            app.decodeButton = uibutton(app.UIFigure, 'push');
            app.decodeButton.ButtonPushedFcn = createCallbackFcn(app, @decodeButtonPushed, true);
            app.decodeButton.Text = 'decode';
            app.decodedbitsTextAreaLabel = uilabel(app.UIFigure);
            app.decodedbitsTextAreaLabel.Text = {'decoded'; 'bits'};
            app.decodedbitsTextArea = uitextarea(app.UIFigure);
            app.plotBERButton = uibutton(app.UIFigure, 'push');
            app.plotBERButton.ButtonPushedFcn = createCallbackFcn(app, @plotBERButtonPushed, true);
            app.plotBERButton.Text = 'plot BER';
            app.retrieveButton = uibutton(app.UIFigure, 'push');
            app.retrieveButton.ButtonPushedFcn = createCallbackFcn(app, @retrieveButtonPushed, true);
            app.retrieveButton.Text = 'retrieve';
            app.modulatedbitswithoutcodingTextAreaLabel = uilabel(app.UIFigure);
            app.modulatedbitswithoutcodingTextAreaLabel.Text = {'modulated bits'; 'without coding'};
            app.modulatedbitswithoutcodingTextArea = uitextarea(app.UIFigure);
            app.demodBitswithoutcodingTextAreaLabel = uilabel(app.UIFigure);
            app.demodBitswithoutcodingTextAreaLabel.Text = {'demodBits '; 'without coding'};
            app.demodBitswithoutcodingTextArea = uitextarea(app.UIFigure);
            app.modulateuncodedButton = uibutton(app.UIFigure, 'push');
            app.modulateuncodedButton.ButtonPushedFcn = createCallbackFcn(app, @modulateuncodedButtonPushed, true);
            app.modulateuncodedButton.Text = 'modulate uncoded';
            app.retrieveuncodedButton = uibutton(app.UIFigure, 'push');
            app.retrieveuncodedButton.ButtonPushedFcn = createCallbackFcn(app, @retrieveuncodedButtonPushed, true);
            app.retrieveuncodedButton.Text = 'retrieve uncoded';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COMMSYS_GIT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end